classdef A2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        UR3Tab                    matlab.ui.container.Tab
        q1Slider                  matlab.ui.control.Slider
        q1SliderLabel             matlab.ui.control.Label
        q2Slider                  matlab.ui.control.Slider
        q2SliderLabel             matlab.ui.control.Label
        q3Slider                  matlab.ui.control.Slider
        q3SliderLabel             matlab.ui.control.Label
        q4Slider                  matlab.ui.control.Slider
        q4SliderLabel             matlab.ui.control.Label
        q5Slider                  matlab.ui.control.Slider
        q5SliderLabel             matlab.ui.control.Label
        q6Slider                  matlab.ui.control.Slider
        q6SliderLabel             matlab.ui.control.Label
        XButton_2                 matlab.ui.control.Button
        XButton                   matlab.ui.control.Button
        ZButton_2                 matlab.ui.control.Button
        YButton                   matlab.ui.control.Button
        YButton_2                 matlab.ui.control.Button
        ZButton                   matlab.ui.control.Button
        ManualControlButton       matlab.ui.control.StateButton
        testButton                matlab.ui.control.Button
        UR20Tab                   matlab.ui.container.Tab
        q1Slider_2Label           matlab.ui.control.Label
        q1Slider_2                matlab.ui.control.Slider
        q2Slider_2Label           matlab.ui.control.Label
        q2Slider_2                matlab.ui.control.Slider
        q3Slider_2Label           matlab.ui.control.Label
        q3Slider_2                matlab.ui.control.Slider
        q4Slider_2Label           matlab.ui.control.Label
        q4Slider_2                matlab.ui.control.Slider
        q5Slider_2Label           matlab.ui.control.Label
        q5Slider_2                matlab.ui.control.Slider
        q6Slider_2Label           matlab.ui.control.Label
        q6Slider_2                matlab.ui.control.Slider
        XButton_3                 matlab.ui.control.Button
        XButton_4                 matlab.ui.control.Button
        ZButton_3                 matlab.ui.control.Button
        YButton_3                 matlab.ui.control.Button
        YButton_4                 matlab.ui.control.Button
        ZButton_4                 matlab.ui.control.Button
        ManualControlButton_2     matlab.ui.control.StateButton
        testButton_2              matlab.ui.control.Button
        Lamp_2                    matlab.ui.control.Lamp
        ESTOPButton_2             matlab.ui.control.StateButton
        STARTButton               matlab.ui.control.Button
        CollisionavoidanceButton  matlab.ui.control.StateButton
        TextArea                  matlab.ui.control.TextArea
        VisualServoButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        status = 0;% Description
        stop = false; % Description
        simRunning = false;
        manualControlOn = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ESTOPButton_2
        function ESTOPButton_2ValueChanged(app, event)
            value = app.ESTOPButton_2.Value;
            if value                                                        %if button pressed turn light red
                app.Lamp_2.Color = [1 0 0];                                 
                app.stop = true;
                app.status = 3;
                text = sprintf('stopped');
                app.q1Slider.Enable = false;
                app.q2Slider.Enable = false;
                app.q3Slider.Enable = false;
                app.q4Slider.Enable = false;
                app.q5Slider.Enable = false;
                app.q6Slider.Enable = false;
                app.q1Slider_2.Enable = false;
                app.q2Slider_2.Enable = false;
                app.q3Slider_2.Enable = false;
                app.q4Slider_2.Enable = false;
                app.q5Slider_2.Enable = false;
                app.q6Slider_2.Enable = false;
            else                                                            %else button is not pressed
                app.stop = false;
                app.Lamp_2.Color = [1 1 0];
                text = sprintf('paused');
                app.q1Slider.Enable = true;
                app.q2Slider.Enable = true;
                app.q3Slider.Enable = true;
                app.q4Slider.Enable = true;
                app.q5Slider.Enable = true;
                app.q6Slider.Enable = true;
                app.q1Slider_2.Enable = true;
                app.q2Slider_2.Enable = true;
                app.q3Slider_2.Enable = true;
                app.q4Slider_2.Enable = true;
                app.q5Slider_2.Enable = true;
                app.q6Slider_2.Enable = true;
            end
            assignin("base","status",app.status);
            assignin("base",'value',value);
            app.TextArea.Value = text;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.status == 0 || app.status == 1 && app.stop == false      %if status off or on and estop not toggled on turn light green
                app.Lamp_2.Color = [0 1 0];
                app.status = 1;
                text = sprintf('on');
%                 if app.simRunning == false
%                     Main
%                     app.simRunning = true;
%                 end
            elseif app.status == 3 && app.stop == false                     %if status stopped and estop not toggled on turn light green
                app.Lamp_2.Color = [0 1 0];
                app.status = 1;
                text = sprintf('on');
            else
                text = sprintf('stopped');
            end
            assignin("base","status",app.status);
            app.TextArea.Value = text;
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
        end

        % Value changed function: q2Slider_2
        function q2Slider_2ValueChanged(app, event)
            value = app.q2Slider_2.Value;
        end

        % Value changed function: q3Slider_2
        function q3Slider_2ValueChanged(app, event)
            value = app.q3Slider_2.Value;
        end

        % Value changed function: q4Slider_2
        function q4Slider_2ValueChanged(app, event)
            value = app.q4Slider_2.Value;
        end

        % Value changed function: q5Slider_2
        function q5Slider_2ValueChanged(app, event)
            value = app.q5Slider_2.Value;
        end

        % Value changed function: q6Slider_2
        function q6Slider_2ValueChanged(app, event)
            value = app.q6Slider_2.Value;
        end

        % Value changed function: q1Slider_2
        function q1Slider_2ValueChanged(app, event)
            value = app.q1Slider_2.Value;
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            value = app.q6Slider.Value;
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            
        end

        % Value changed function: ManualControlButton
        function ManualControlButtonValueChanged(app, event)
            value = app.ManualControlButton.Value;
            robot = 1;
            if app.manualControlOn == false
                manualControl(app,robot)
            end
        end

        % Value changed function: ManualControlButton_2
        function ManualControlButton_2ValueChanged(app, event)
            value = app.ManualControlButton_2.Value;
            robot = 2;
            if app.manualControlOn == false
                manualControl(app,robot)
            end
        end

        % Value changed function: CollisionavoidanceButton
        function CollisionavoidanceButtonValueChanged(app, event)
            value = app.CollisionavoidanceButton.Value;
            collisionAvoidance(app)
        end

        % Button pushed function: testButton_2
        function testButton_2Pushed(app, event)
            robot = 2;
            manualControl(app,robot)
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            robot = 1;
            manualControl(app,robot)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.q1Slider = uislider(app.UR3Tab);
            app.q1Slider.Limits = [-360 360];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.Tooltip = {''};
            app.q1SliderLabel = uilabel(app.UR3Tab);
            app.q1SliderLabel.Text = 'q1';
            app.q2Slider = uislider(app.UR3Tab);
            app.q2Slider.Limits = [-360 360];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UR3Tab);
            app.q2SliderLabel.Text = 'q2';
            app.q3Slider = uislider(app.UR3Tab);
            app.q3Slider.Limits = [-360 360];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UR3Tab);
            app.q3SliderLabel.Text = 'q3';
            app.q4Slider = uislider(app.UR3Tab);
            app.q4Slider.Limits = [-360 360];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UR3Tab);
            app.q4SliderLabel.Text = 'q4';
            app.q5Slider = uislider(app.UR3Tab);
            app.q5Slider.Limits = [-360 360];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UR3Tab);
            app.q5SliderLabel.Text = 'q5';
            app.q6Slider = uislider(app.UR3Tab);
            app.q6Slider.Limits = [-90 90];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.UR3Tab);
            app.q6SliderLabel.Text = 'q6';
            app.XButton_2 = uibutton(app.UR3Tab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.XButton = uibutton(app.UR3Tab, 'push');
            app.XButton.Text = 'X+';
            app.ZButton_2 = uibutton(app.UR3Tab, 'push');
            app.ZButton_2.Text = 'Z-';
            app.YButton = uibutton(app.UR3Tab, 'push');
            app.YButton.Text = 'Y-';
            app.YButton_2 = uibutton(app.UR3Tab, 'push');
            app.YButton_2.Text = 'Y+';
            app.ZButton = uibutton(app.UR3Tab, 'push');
            app.ZButton.Text = 'Z+';
            app.ManualControlButton = uibutton(app.UR3Tab, 'state');
            app.ManualControlButton.ValueChangedFcn = createCallbackFcn(app, @ManualControlButtonValueChanged, true);
            app.ManualControlButton.Text = 'Manual Control';
            app.testButton = uibutton(app.UR3Tab, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.UR20Tab = uitab(app.TabGroup);
            app.UR20Tab.Title = 'UR20';
            app.q1Slider_2Label = uilabel(app.UR20Tab);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.UR20Tab);
            app.q1Slider_2.Limits = [-360 360];
            app.q1Slider_2.ValueChangedFcn = createCallbackFcn(app, @q1Slider_2ValueChanged, true);
            app.q2Slider_2Label = uilabel(app.UR20Tab);
            app.q2Slider_2Label.Text = 'q2';
            app.q2Slider_2 = uislider(app.UR20Tab);
            app.q2Slider_2.Limits = [-360 360];
            app.q2Slider_2.ValueChangedFcn = createCallbackFcn(app, @q2Slider_2ValueChanged, true);
            app.q3Slider_2Label = uilabel(app.UR20Tab);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.UR20Tab);
            app.q3Slider_2.Limits = [-360 360];
            app.q3Slider_2.ValueChangedFcn = createCallbackFcn(app, @q3Slider_2ValueChanged, true);
            app.q4Slider_2Label = uilabel(app.UR20Tab);
            app.q4Slider_2Label.Text = 'q4';
            app.q4Slider_2 = uislider(app.UR20Tab);
            app.q4Slider_2.Limits = [-360 360];
            app.q4Slider_2.ValueChangedFcn = createCallbackFcn(app, @q4Slider_2ValueChanged, true);
            app.q5Slider_2Label = uilabel(app.UR20Tab);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.UR20Tab);
            app.q5Slider_2.Limits = [-360 360];
            app.q5Slider_2.ValueChangedFcn = createCallbackFcn(app, @q5Slider_2ValueChanged, true);
            app.q6Slider_2Label = uilabel(app.UR20Tab);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.UR20Tab);
            app.q6Slider_2.Limits = [-90 90];
            app.q6Slider_2.ValueChangedFcn = createCallbackFcn(app, @q6Slider_2ValueChanged, true);
            app.XButton_3 = uibutton(app.UR20Tab, 'push');
            app.XButton_3.Text = 'X-';
            app.XButton_4 = uibutton(app.UR20Tab, 'push');
            app.XButton_4.Text = 'X+';
            app.ZButton_3 = uibutton(app.UR20Tab, 'push');
            app.ZButton_3.Text = 'Z-';
            app.YButton_3 = uibutton(app.UR20Tab, 'push');
            app.YButton_3.Text = 'Y-';
            app.YButton_4 = uibutton(app.UR20Tab, 'push');
            app.YButton_4.Text = 'Y+';
            app.ZButton_4 = uibutton(app.UR20Tab, 'push');
            app.ZButton_4.Text = 'Z+';
            app.ManualControlButton_2 = uibutton(app.UR20Tab, 'state');
            app.ManualControlButton_2.ValueChangedFcn = createCallbackFcn(app, @ManualControlButton_2ValueChanged, true);
            app.ManualControlButton_2.Text = 'Manual Control';
            app.testButton_2 = uibutton(app.UR20Tab, 'push');
            app.testButton_2.ButtonPushedFcn = createCallbackFcn(app, @testButton_2Pushed, true);
            app.testButton_2.Text = 'test';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Tooltip = {''};
            app.ESTOPButton_2 = uibutton(app.UIFigure, 'state');
            app.ESTOPButton_2.ValueChangedFcn = createCallbackFcn(app, @ESTOPButton_2ValueChanged, true);
            app.ESTOPButton_2.Text = 'E STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.CollisionavoidanceButton = uibutton(app.UIFigure, 'state');
            app.CollisionavoidanceButton.ValueChangedFcn = createCallbackFcn(app, @CollisionavoidanceButtonValueChanged, true);
            app.CollisionavoidanceButton.Text = 'Collision avoidance';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'off'};
            app.VisualServoButton = uibutton(app.UIFigure, 'push');
            app.VisualServoButton.Text = 'Visual Servo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end